classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputMenu             matlab.ui.container.Menu
        AnalysisMenu          matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        AmplitudeSliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        EntercsvvalueLabel    matlab.ui.control.Label
        IN                    matlab.ui.control.EditField
        InputLabel            matlab.ui.control.Label
        Start                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)

           
        end

        % Menu selected function: AnalysisMenu
        function Selection(app, event)
            
        end

        % Button pushed function: Start
        function start_function(app, event)
            
class(app.IN)
input_file = app.IN;
% genes with read lower than this threshold will not be used in analysis
count_threshold = 30; 
% size of the bin
bin_size = 200;
%%
disp(inputname);
[name, pos] = getinsitudata(input_file);
[uNames, ~, idxName] = unique(name);
cNames = hist(idxName, 1:max(idxName));

% remove NNNN and any reads<count_threshold
[name, pos] = removereads(name, [uNames(cNames<count_threshold); {'NNNN'}], pos);
[uNames, ~, idxName] = unique(name);

% bin
plot_all_onblack(input_file);
show_square_lines(gcf, bin_size, bin_size, 'w');
pos_bin = ceil(pos/bin_size);
pos_bin = max(pos_bin(:,1))*(pos_bin(:,1)-1) + pos_bin(:,2);


ksstat = zeros(length(uNames), 2);
for i = 1:length(uNames)
    for j = i:length(uNames)
        [~, p, ks2stat] = kstest2(pos_bin(idxName==i), pos_bin(idxName==j));
        ksstat(i,j,1) = ks2stat;
        ksstat(i,j,2) = p;
        ksstat(j,i,1) = ks2stat;
        ksstat(j,i,2) = p;
    end
end
% figure; imagesc(ksstat(:,:,2));
% set(gca, 'xtick', 1:length(uniName), 'xticklabel', uniName,...
%     'ytick', 1:length(uniName), 'yticklabel', uniName,...
%     'xticklabelrotation', 90);


% sorting
L = linkage(ksstat(:,:,2));
order = dendroperm(L);

figure; imagesc(ksstat(order,order,2)); grid on;
set(gca, 'xtick', 1:length(uNames), 'xticklabel', uNames(order),...
    'ytick', 1:length(uNames), 'yticklabel', uNames(order),...
    'xticklabelrotation', 90);

c = colorbar;
c.Label.String = 'Kolmogorov-Smirnov test p-value';
c.Label.FontSize = 12;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputMenu = uimenu(app.UIFigure);
            app.InputMenu.Text = 'Input';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @Selection, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.EntercsvvalueLabel = uilabel(app.UIFigure);
            app.EntercsvvalueLabel.Text = 'Enter .csv value';
            app.IN = uieditfield(app.UIFigure, 'text');
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @start_function, true);
            app.Start.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end